classdef EE430_Project1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        MessageBoxTextArea              matlab.ui.control.TextArea
        MessageBoxTextAreaLabel         matlab.ui.control.Label
        SoundSignalPlotPanel            matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        ParametersPanel                 matlab.ui.container.Panel
        IfyouwanttoplotthesoundsignalpleaseswitchonfromPlotSignalLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        PlaySoundSwitch                 matlab.ui.control.Switch
        PlaySoundSwitchLabel            matlab.ui.control.Label
        PlotSignalSwitch                matlab.ui.control.Switch
        PlotSignalSwitchLabel           matlab.ui.control.Label
        CustomInputPanel                matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        SinusoidalTab                   matlab.ui.container.Tab
        DurationSecondEditField         matlab.ui.control.NumericEditField
        DurationSecondEditFieldLabel    matlab.ui.control.Label
        GenerateSignalButton            matlab.ui.control.Button
        FrequencyHertzEditField         matlab.ui.control.NumericEditField
        FrequencyHertzEditFieldLabel    matlab.ui.control.Label
        PhaseShiftHertzEditField        matlab.ui.control.NumericEditField
        PhaseShiftHertzEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        WindowedTab                     matlab.ui.container.Tab
        GenerateSignalButton_2          matlab.ui.control.Button
        WindowTypeEditField             matlab.ui.control.EditField
        WindowTypeEditFieldLabel        matlab.ui.control.Label
        StartingTimeSecondEditField     matlab.ui.control.NumericEditField
        StartingTimeSecondEditFieldLabel  matlab.ui.control.Label
        DurationSecondEditField_2       matlab.ui.control.NumericEditField
        DurationSecondEditField_2Label  matlab.ui.control.Label
        PhaseShiftDegreeEditField_2     matlab.ui.control.NumericEditField
        PhaseShiftDegreeEditField_2Label  matlab.ui.control.Label
        FrequencyDegreeEditField_2      matlab.ui.control.NumericEditField
        FrequencyDegreeEditField_2Label  matlab.ui.control.Label
        AmplitudeEditField_2            matlab.ui.control.NumericEditField
        AmplitudeEditField_2Label       matlab.ui.control.Label
        CustomTab                       matlab.ui.container.Tab
        StartTimeSecondsEditField       matlab.ui.control.NumericEditField
        StartTimeSecondsEditFieldLabel  matlab.ui.control.Label
        AddSignalButton                 matlab.ui.control.Button
        SamplingRateEditField           matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        FrequenciesDegreeEditField      matlab.ui.control.EditField
        FrequenciesDegreeEditFieldLabel  matlab.ui.control.Label
        PhasesDegreeEditField           matlab.ui.control.EditField
        PhasesDegreeEditFieldLabel      matlab.ui.control.Label
        AmplitudesEditField             matlab.ui.control.EditField
        AmplitudesEditFieldLabel        matlab.ui.control.Label
        NumberOfSignalsEditField        matlab.ui.control.NumericEditField
        NumberOfSignalsEditFieldLabel   matlab.ui.control.Label
        DurationSecondEditField_3       matlab.ui.control.NumericEditField
        DurationSecondEditField_3Label  matlab.ui.control.Label
        FromFilePanel                   matlab.ui.container.Panel
        ChosenFileEditField             matlab.ui.control.EditField
        ChosenFileEditFieldLabel        matlab.ui.control.Label
        ReadFileButton                  matlab.ui.control.Button
        FromMicrophonePanel             matlab.ui.container.Panel
        StartRecordingButton            matlab.ui.control.Button
        LengthsecondsEditField          matlab.ui.control.NumericEditField
        LengthsecondsEditFieldLabel     matlab.ui.control.Label
        SampleNumberEditField           matlab.ui.control.NumericEditField
        SampleNumberEditFieldLabel      matlab.ui.control.Label
        SpectogramTab                   matlab.ui.container.Tab
        GraphButton                     matlab.ui.control.Button
        ShiftAmountEditField            matlab.ui.control.NumericEditField
        ShiftAmountEditFieldLabel       matlab.ui.control.Label
        WindowTypeDropDown              matlab.ui.control.DropDown
        WindowTypeDropDownLabel         matlab.ui.control.Label
        WindowLengthEditField           matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        duration
        time = 0
        time_holder
        fs
        signal
        signal_holder
        spectogram_window
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SampleNumberEditField
        function SampleNumberEditFieldValueChanged(app, event)
            app.SampleNumberEditField.Value;
        end

        % Value changed function: LengthsecondsEditField
        function LengthsecondsEditFieldValueChanged(app, event)
            app.LengthsecondsEditField.Value;
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            app.fs = app.SampleNumberEditField.Value();
            app.duration = app.LengthsecondsEditField.Value();
            app.MessageBoxTextArea.Value = 'Recording is started...';
            recObj = audiorecorder(app.fs,24,1);
            recordblocking(recObj, app.duration);
            app.signal = getaudiodata(recObj);
            app.time = linspace(0, app.duration, length(app.signal));
            app.MessageBoxTextArea.Value = 'Sound recorded...';

        end

        % Value changed function: PlotSignalSwitch
        function PlotSignalSwitchValueChanged(app, event)
            app.PlotSignalSwitch.Value;

            if isequal(app.PlotSignalSwitch.Value, "On")
                app.MessageBoxTextArea.Value = 'Plotting the signal...';
                plot(app.UIAxes, app.time, app.signal);
                pause(2);
                app.MessageBoxTextArea.Value = '';
            end
            if isequal(app.PlotSignalSwitch.Value, "Off")
                plot(app.UIAxes, 0, 0);
            end    
        end

        % Button pushed function: ReadFileButton
        function ReadFileButtonPushed(app, event)
            file = uigetfile;
            [app.signal,app.fs] = audioread(file);
            app.ChosenFileEditField.Value = path;
            app.time = linspace(0, length(app.signal)/app.fs, length(app.signal));
            app.MessageBoxTextArea.Value = 'File is red';
            pause(2);
            app.MessageBoxTextArea.Value = '';
        end

        % Value changed function: PlaySoundSwitch
        function PlaySoundSwitchValueChanged(app, event)
            app.PlaySoundSwitch.Value;
            if isequal(app.PlaySoundSwitch.Value, "On")
                app.MessageBoxTextArea.Value = 'Playing the sound...';
                sound(app.signal, app.fs);
                pause(app.duration);
                app.MessageBoxTextArea.Value = '';
            end
        end

        % Value changed function: ChosenFileEditField
        function ChosenFileEditFieldValueChanged(app, event)
            app.ChosenFileEditField.Value;
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            app.AmplitudeEditField.Value;
        end

        % Value changed function: FrequencyHertzEditField
        function FrequencyHertzEditFieldValueChanged(app, event)
            app.FrequencyHertzEditField.Value;
        end

        % Value changed function: PhaseShiftHertzEditField
        function PhaseShiftHertzEditFieldValueChanged(app, event)
            app.PhaseShiftHertzEditField.Value;
        end

        % Button pushed function: GenerateSignalButton
        function GenerateSignalButtonPushed(app, event)
            % User input for amplitude, frequency, and phase in degrees
            amplitude = app.AmplitudeEditField.Value;
            frequency = app.FrequencyHertzEditField.Value;
            phase_degrees = app.PhaseShiftHertzEditField.Value;

            % Convert phase from degrees to radians
            phase_radians = deg2rad(phase_degrees);

            % Define the time vector (from 0 to duration second with a certain sampling rate)
            app.fs = 40000;
            app.duration = app.DurationSecondEditField.Value;
            app.time = linspace(0, app.duration, app.duration * app.fs);

            % Generate the sinusoidal signal
            app.signal = amplitude * cos(2 * pi * frequency * app.time + phase_radians);
            app.MessageBoxTextArea.Value = 'Signal is generated';
            pause(2);
            app.MessageBoxTextArea.Value = '';
        end

        % Value changed function: DurationSecondEditField
        function DurationSecondEditFieldValueChanged(app, event)
            app.DurationSecondEditField.Value;
        end

        % Value changed function: AmplitudeEditField_2
        function AmplitudeEditField_2ValueChanged(app, event)
            app.AmplitudeEditField_2.Value;
            app.MessageBoxTextArea.Value = 'Available window types: Rectangular, Triangular, Blackman';
        end

        % Value changed function: FrequencyDegreeEditField_2
        function FrequencyDegreeEditField_2ValueChanged(app, event)
            app.FrequencyDegreeEditField_2.Value;
        end

        % Value changed function: PhaseShiftDegreeEditField_2
        function PhaseShiftDegreeEditField_2ValueChanged(app, event)
            app.PhaseShiftDegreeEditField_2.Value;
        end

        % Value changed function: StartingTimeSecondEditField
        function StartingTimeSecondEditFieldValueChanged(app, event)
            app.StartingTimeSecondEditField.Value;
        end

        % Value changed function: DurationSecondEditField_2
        function DurationSecondEditField_2ValueChanged(app, event)
            app.DurationSecondEditField_2.Value;
        end

        % Value changed function: WindowTypeEditField
        function WindowTypeEditFieldValueChanged(app, event)
            app.WindowTypeEditField.Value;
        end

        % Button pushed function: GenerateSignalButton_2
        function GenerateSignalButton_2Pushed(app, event)
            % User input for amplitude, frequency, and phase in degrees
            amplitude = app.AmplitudeEditField_2.Value;
            frequency = app.FrequencyDegreeEditField_2.Value;
            phase_degrees = app.PhaseShiftDegreeEditField_2.Value;
            start_time = app.StartingTimeSecondEditField.Value;
            app.duration = app.DurationSecondEditField_2.Value;
            window_type = app.WindowTypeEditField.Value;

            % Convert phase from degrees to radians
            phase_radians = deg2rad(phase_degrees);
               
            % Generate time signal
            app.fs = 40000;
            app.time = linspace(start_time-2, start_time + app.duration+2, app.duration * app.fs);

            % Generate the window
            switch lower(window_type)
                case 'rectangular'
                    window = ones(size(app.time));
                    window(app.time < start_time | app.time >= app.duration + start_time) = 0;
                case 'triangular'
                    window = 1 - abs((app.time - start_time - app.duration / 2) / (app.duration / 2));
                    window(app.time < start_time | app.time >= start_time + app.duration) = 0;
                case 'blackman'
                    % Generate the Blackman window function
                    alpha = 0.16;
                    a0 = 0.5 * (1 - alpha);
                    a1 = 0.5;
                    a2 = 0.5 * alpha;
                    window = a0 - a1 * cos(2 * pi * (app.time - start_time - app.duration) / app.duration) + a2 * cos(4 * pi * (app.time - start_time - app.duration) / app.duration);
                    window(app.time < start_time | app.time >= start_time + app.duration) = 0;
                otherwise
                    error('Unsupported window function. Please choose rectangular, triangular, or blackman.');
            end
            
             % Generate the sinusoidal signal
            app.signal = amplitude * cos(2 * pi * frequency * (app.time - start_time) + phase_radians) .* window;
            app.MessageBoxTextArea.Value = 'Signal is generated';
            pause(2);
            app.MessageBoxTextArea.Value = '';
        end

        % Value changed function: DurationSecondEditField_3
        function DurationSecondEditField_3ValueChanged(app, event)
            app.DurationSecondEditField_3.Value;
        end

        % Value changed function: NumberOfSignalsEditField
        function NumberOfSignalsEditFieldValueChanged(app, event)
            app.NumberOfSignalsEditField.Value;
        end

        % Value changed function: AmplitudesEditField
        function AmplitudesEditFieldValueChanged(app, event)
            app.AmplitudesEditField.Value; 
        end

        % Value changed function: FrequenciesDegreeEditField
        function FrequenciesDegreeEditFieldValueChanged(app, event)
            app.FrequenciesDegreeEditField.Value;            
        end

        % Value changed function: PhasesDegreeEditField
        function PhasesDegreeEditFieldValueChanged(app, event)
            app.PhasesDegreeEditField.Value;
        end

        % Value changed function: WindowLengthEditField
        function WindowLengthEditFieldValueChanged(app, event)
             app.WindowLengthEditField.Value;
        end

        % Drop down opening function: WindowTypeDropDown
        function WindowTypeDropDownOpening(app, event)
            app.WindowTypeDropDown.Value;
        end

        % Value changed function: ShiftAmountEditField
        function ShiftAmountEditFieldValueChanged(app, event)
            app.ShiftAmountEditField.Value;
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            switch app.WindowTypeDropDown.Value
              case "Rectangular Window"
                  app.spectogram_window = rectwin(app.WindowLengthEditField.Value);
              case "Hamming Window"
                  app.spectogram_window = hamming(app.WindowLengthEditField.Value);
            end
            
            spectrogram_window_length = app.WindowLengthEditField.Value;
            spectrogram_shift = app.ShiftAmountEditField.Value;

            stft_row_number = ceil((1+spectrogram_window_length)/2); %for only positive frequencies since magnitude is even
            stft_column_number = 1 + floor((length(app.signal)-spectrogram_window_length)/spectrogram_shift);
            stft_matrix = zeros(stft_row_number,stft_column_number);

            shift_number = 0;
            while((shift_number*spectrogram_shift) + spectrogram_window_length <= length(app.signal))
                window = app.signal((shift_number*spectrogram_shift)+1:(shift_number*spectrogram_shift)+spectrogram_window_length) .* app.spectogram_window;
                window_fft = fft(window, spectrogram_window_length);
                stft_matrix(:,shift_number+1) = window_fft(1:stft_row_number);
                shift_number = shift_number + 1;
            end    

            freqs = (0:stft_row_number-1)*app.fs / spectrogram_window_length;
            time = [0:1/length(freqs):length(app.signal)/app.fs];%(spectrogram_window_length/2 : spectrogram_shift : length(signal)-spectrogram_window_length/2-1) / fs;

            imagesc(app.UIAxes2,time,freqs,20*log10(abs(stft_matrix)));
            cbar = colorbar(app.UIAxes2);
            ylabel(cbar, 'Power/Frequency(dB/Hz)');

        end

        % Value changed function: SamplingRateEditField
        function SamplingRateEditFieldValueChanged(app, event)
            app.SamplingRateEditField.Value;
            
        end

        % Value changed function: StartTimeSecondsEditField
        function StartTimeSecondsEditFieldValueChanged(app, event)
            app.StartTimeSecondsEditField.Value;
        end

        % Button pushed function: AddSignalButton
        function AddSignalButtonPushed(app, event)
            % User input for amplitude, frequency, and phase in degrees
            amplitudes = str2num(app.AmplitudesEditField.Value); %#ok<*ST2NM> 
            frequencies = str2num(app.FrequenciesDegreeEditField.Value);
            phase_degrees = str2num(app.PhasesDegreeEditField.Value);
            start_time = app.StartTimeSecondsEditField.Value;
            num_components = app.NumberOfSignalsEditField.Value;

            % Convert phase from degrees to radians
            phases_radians = deg2rad(phase_degrees);

            % Sampling rate and duration
            app.fs = app.SamplingRateEditField.Value;
            app.duration = app.DurationSecondEditField_3.Value;
            app.time_holder = linspace(start_time, app.duration+start_time, app.duration * app.fs);

            % Initialize the signal
            app.signal_holder = zeros(1, length(app.time_holder));

            % Generate the multi-component signal
            for m = 1:num_components
                component = amplitudes(m) * cos(2 * pi * frequencies(m) * app.time_holder + phases_radians(m));
                app.signal_holder = app.signal_holder + component;
            end
            if (app.time == 0)
                app.time = app.time_holder;
            else
                app.time = [app.time, app.time_holder];
            end
            app.signal = [app.signal, app.signal_holder];
            app.MessageBoxTextArea.Value = 'Signal is added';
            pause(2);
            app.MessageBoxTextArea.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.FromMicrophonePanel = uipanel(app.InputTab);
            app.FromMicrophonePanel.Title = 'From Microphone';
            app.SampleNumberEditFieldLabel = uilabel(app.FromMicrophonePanel);
            app.SampleNumberEditFieldLabel.Text = 'Sample Number';
            app.SampleNumberEditField = uieditfield(app.FromMicrophonePanel, 'numeric');
            app.SampleNumberEditField.ValueChangedFcn = createCallbackFcn(app, @SampleNumberEditFieldValueChanged, true);
            app.LengthsecondsEditFieldLabel = uilabel(app.FromMicrophonePanel);
            app.LengthsecondsEditFieldLabel.Text = 'Length (seconds)';
            app.LengthsecondsEditField = uieditfield(app.FromMicrophonePanel, 'numeric');
            app.LengthsecondsEditField.ValueChangedFcn = createCallbackFcn(app, @LengthsecondsEditFieldValueChanged, true);
            app.StartRecordingButton = uibutton(app.FromMicrophonePanel, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.FromFilePanel = uipanel(app.InputTab);
            app.FromFilePanel.Title = 'From File';
            app.ReadFileButton = uibutton(app.FromFilePanel, 'push');
            app.ReadFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFileButton.Text = 'Read File';
            app.ChosenFileEditFieldLabel = uilabel(app.FromFilePanel);
            app.ChosenFileEditFieldLabel.Text = 'Chosen File';
            app.ChosenFileEditField = uieditfield(app.FromFilePanel, 'text');
            app.ChosenFileEditField.ValueChangedFcn = createCallbackFcn(app, @ChosenFileEditFieldValueChanged, true);
            app.CustomInputPanel = uipanel(app.InputTab);
            app.CustomInputPanel.Title = 'Custom Input';
            app.TabGroup2 = uitabgroup(app.CustomInputPanel);
            app.SinusoidalTab = uitab(app.TabGroup2);
            app.SinusoidalTab.Title = 'Sinusoidal';
            app.AmplitudeEditFieldLabel = uilabel(app.SinusoidalTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SinusoidalTab, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.PhaseShiftHertzEditFieldLabel = uilabel(app.SinusoidalTab);
            app.PhaseShiftHertzEditFieldLabel.Text = 'Phase Shift(Hertz)';
            app.PhaseShiftHertzEditField = uieditfield(app.SinusoidalTab, 'numeric');
            app.PhaseShiftHertzEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseShiftHertzEditFieldValueChanged, true);
            app.FrequencyHertzEditFieldLabel = uilabel(app.SinusoidalTab);
            app.FrequencyHertzEditFieldLabel.Text = 'Frequency(Hertz)';
            app.FrequencyHertzEditField = uieditfield(app.SinusoidalTab, 'numeric');
            app.FrequencyHertzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHertzEditFieldValueChanged, true);
            app.GenerateSignalButton = uibutton(app.SinusoidalTab, 'push');
            app.GenerateSignalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalButtonPushed, true);
            app.GenerateSignalButton.Text = 'Generate Signal';
            app.DurationSecondEditFieldLabel = uilabel(app.SinusoidalTab);
            app.DurationSecondEditFieldLabel.Text = 'Duration(Second)';
            app.DurationSecondEditField = uieditfield(app.SinusoidalTab, 'numeric');
            app.DurationSecondEditField.ValueChangedFcn = createCallbackFcn(app, @DurationSecondEditFieldValueChanged, true);
            app.WindowedTab = uitab(app.TabGroup2);
            app.WindowedTab.Title = 'Windowed';
            app.AmplitudeEditField_2Label = uilabel(app.WindowedTab);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.WindowedTab, 'numeric');
            app.AmplitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditField_2ValueChanged, true);
            app.FrequencyDegreeEditField_2Label = uilabel(app.WindowedTab);
            app.FrequencyDegreeEditField_2Label.Text = 'Frequency(Degree)';
            app.FrequencyDegreeEditField_2 = uieditfield(app.WindowedTab, 'numeric');
            app.FrequencyDegreeEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrequencyDegreeEditField_2ValueChanged, true);
            app.PhaseShiftDegreeEditField_2Label = uilabel(app.WindowedTab);
            app.PhaseShiftDegreeEditField_2Label.Text = 'Phase Shift(Degree)';
            app.PhaseShiftDegreeEditField_2 = uieditfield(app.WindowedTab, 'numeric');
            app.PhaseShiftDegreeEditField_2.ValueChangedFcn = createCallbackFcn(app, @PhaseShiftDegreeEditField_2ValueChanged, true);
            app.DurationSecondEditField_2Label = uilabel(app.WindowedTab);
            app.DurationSecondEditField_2Label.Text = 'Duration(Second)';
            app.DurationSecondEditField_2 = uieditfield(app.WindowedTab, 'numeric');
            app.DurationSecondEditField_2.ValueChangedFcn = createCallbackFcn(app, @DurationSecondEditField_2ValueChanged, true);
            app.StartingTimeSecondEditFieldLabel = uilabel(app.WindowedTab);
            app.StartingTimeSecondEditFieldLabel.Text = 'Starting Time(Second)';
            app.StartingTimeSecondEditField = uieditfield(app.WindowedTab, 'numeric');
            app.StartingTimeSecondEditField.ValueChangedFcn = createCallbackFcn(app, @StartingTimeSecondEditFieldValueChanged, true);
            app.WindowTypeEditFieldLabel = uilabel(app.WindowedTab);
            app.WindowTypeEditFieldLabel.Text = 'Window Type';
            app.WindowTypeEditField = uieditfield(app.WindowedTab, 'text');
            app.WindowTypeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowTypeEditFieldValueChanged, true);
            app.GenerateSignalButton_2 = uibutton(app.WindowedTab, 'push');
            app.GenerateSignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalButton_2Pushed, true);
            app.GenerateSignalButton_2.Text = 'Generate Signal';
            app.CustomTab = uitab(app.TabGroup2);
            app.CustomTab.Title = 'Custom';
            app.DurationSecondEditField_3Label = uilabel(app.CustomTab);
            app.DurationSecondEditField_3Label.Text = 'Duration(Second)';
            app.DurationSecondEditField_3 = uieditfield(app.CustomTab, 'numeric');
            app.DurationSecondEditField_3.ValueChangedFcn = createCallbackFcn(app, @DurationSecondEditField_3ValueChanged, true);
            app.NumberOfSignalsEditFieldLabel = uilabel(app.CustomTab);
            app.NumberOfSignalsEditFieldLabel.Text = 'Number Of Signals';
            app.NumberOfSignalsEditField = uieditfield(app.CustomTab, 'numeric');
            app.NumberOfSignalsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfSignalsEditFieldValueChanged, true);
            app.AmplitudesEditFieldLabel = uilabel(app.CustomTab);
            app.AmplitudesEditFieldLabel.Text = 'Amplitudes';
            app.AmplitudesEditField = uieditfield(app.CustomTab, 'text');
            app.AmplitudesEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudesEditFieldValueChanged, true);
            app.PhasesDegreeEditFieldLabel = uilabel(app.CustomTab);
            app.PhasesDegreeEditFieldLabel.Text = 'Phases(Degree)';
            app.PhasesDegreeEditField = uieditfield(app.CustomTab, 'text');
            app.PhasesDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @PhasesDegreeEditFieldValueChanged, true);
            app.FrequenciesDegreeEditFieldLabel = uilabel(app.CustomTab);
            app.FrequenciesDegreeEditFieldLabel.Text = 'Frequencies(Degree)';
            app.FrequenciesDegreeEditField = uieditfield(app.CustomTab, 'text');
            app.FrequenciesDegreeEditField.ValueChangedFcn = createCallbackFcn(app, @FrequenciesDegreeEditFieldValueChanged, true);
            app.SamplingRateEditFieldLabel = uilabel(app.CustomTab);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.CustomTab, 'numeric');
            app.SamplingRateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditFieldValueChanged, true);
            app.AddSignalButton = uibutton(app.CustomTab, 'push');
            app.AddSignalButton.ButtonPushedFcn = createCallbackFcn(app, @AddSignalButtonPushed, true);
            app.AddSignalButton.Text = 'Add Signal';
            app.StartTimeSecondsEditFieldLabel = uilabel(app.CustomTab);
            app.StartTimeSecondsEditFieldLabel.Text = 'Start Time(Seconds)';
            app.StartTimeSecondsEditField = uieditfield(app.CustomTab, 'numeric');
            app.StartTimeSecondsEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeSecondsEditFieldValueChanged, true);
            app.ParametersPanel = uipanel(app.InputTab);
            app.ParametersPanel.Title = 'Parameters';
            app.PlotSignalSwitchLabel = uilabel(app.ParametersPanel);
            app.PlotSignalSwitchLabel.Text = 'Plot Signal';
            app.PlotSignalSwitch = uiswitch(app.ParametersPanel, 'slider');
            app.PlotSignalSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotSignalSwitchValueChanged, true);
            app.PlaySoundSwitchLabel = uilabel(app.ParametersPanel);
            app.PlaySoundSwitchLabel.Text = 'Play Sound';
            app.PlaySoundSwitch = uiswitch(app.ParametersPanel, 'slider');
            app.PlaySoundSwitch.ValueChangedFcn = createCallbackFcn(app, @PlaySoundSwitchValueChanged, true);
            app.Label = uilabel(app.ParametersPanel);
            app.Label.Text = {'If you want to play the '; 'sound signal, '; 'please switch on from '; 'Play Sound '};
            app.IfyouwanttoplotthesoundsignalpleaseswitchonfromPlotSignalLabel = uilabel(app.ParametersPanel);
            app.IfyouwanttoplotthesoundsignalpleaseswitchonfromPlotSignalLabel.Text = {'If you want to plot the '; 'sound signal, '; 'please switch on from '; 'Plot Signal '};
            app.SoundSignalPlotPanel = uipanel(app.InputTab);
            app.SoundSignalPlotPanel.Title = 'Sound Signal Plot';
            app.UIAxes = uiaxes(app.SoundSignalPlotPanel);
            title(app.UIAxes, 'Sound Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.MessageBoxTextAreaLabel = uilabel(app.InputTab);
            app.MessageBoxTextAreaLabel.Text = {'Message '; 'Box'};
            app.MessageBoxTextArea = uitextarea(app.InputTab);
            app.SpectogramTab = uitab(app.TabGroup);
            app.SpectogramTab.Title = 'Spectogram';
            app.UIAxes2 = uiaxes(app.SpectogramTab);
            title(app.UIAxes2, 'Spectogram for Window Length')
            xlabel(app.UIAxes2, 'Time (sec)')
            ylabel(app.UIAxes2, 'Frequency (Hz)')
            zlabel(app.UIAxes2, 'Z')
            app.WindowLengthEditFieldLabel = uilabel(app.SpectogramTab);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.SpectogramTab, 'numeric');
            app.WindowLengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLengthEditFieldValueChanged, true);
            app.WindowTypeDropDownLabel = uilabel(app.SpectogramTab);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowTypeDropDown = uidropdown(app.SpectogramTab);
            app.WindowTypeDropDown.Items = {'Rectangular Window', 'Hamming Window'};
            app.WindowTypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @WindowTypeDropDownOpening, true);
            app.WindowTypeDropDown.Value = 'Hamming Window';
            app.ShiftAmountEditFieldLabel = uilabel(app.SpectogramTab);
            app.ShiftAmountEditFieldLabel.Text = 'Shift Amount';
            app.ShiftAmountEditField = uieditfield(app.SpectogramTab, 'numeric');
            app.ShiftAmountEditField.ValueChangedFcn = createCallbackFcn(app, @ShiftAmountEditFieldValueChanged, true);
            app.GraphButton = uibutton(app.SpectogramTab, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EE430_Project1_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end