classdef Part2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TransmitterTab                  matlab.ui.container.Tab
        MessageBoxPanel                 matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        SpectrogramParametersPanel      matlab.ui.container.Panel
        GraphButton                     matlab.ui.control.Button
        ShiftAmountEditField            matlab.ui.control.NumericEditField
        ShiftAmountEditFieldLabel       matlab.ui.control.Label
        WindowLengthEditField           matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        SaveorPlayPanel                 matlab.ui.container.Panel
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        PlayButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        SpectrogramoftheSignalPanel     matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        TimeDomainRepresentationPanel   matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        SignalParametersPanel           matlab.ui.container.Panel
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        RestingDurationsEditField       matlab.ui.control.NumericEditField
        RestingDurationsEditFieldLabel  matlab.ui.control.Label
        SignalingDurationsEditField     matlab.ui.control.NumericEditField
        SignalingDurationsEditFieldLabel  matlab.ui.control.Label
        PressedButtonsPanel             matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        PressedNumbersTextArea          matlab.ui.control.TextArea
        PushButtonPanel                 matlab.ui.container.Panel
        GenerateSignalButton            matlab.ui.control.Button
        Button_11                       matlab.ui.control.Button
        Button_9                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        ReceiverTab                     matlab.ui.container.Tab
        InputPanel                      matlab.ui.container.Panel
        ReadFromFileButton              matlab.ui.control.Button
        StartRecordingButton            matlab.ui.control.Button
        RecordingTimeEditField          matlab.ui.control.NumericEditField
        RecordingTimeEditFieldLabel     matlab.ui.control.Label
        SpectrogramParametersPanel_2    matlab.ui.container.Panel
        GraphButton_2                   matlab.ui.control.Button
        ShiftAmountEditField_2          matlab.ui.control.NumericEditField
        ShiftAmountEditField_2Label     matlab.ui.control.Label
        WindowLengthEditField_2         matlab.ui.control.NumericEditField
        WindowLengthEditField_2Label    matlab.ui.control.Label
        SpectrogramPanel                matlab.ui.container.Panel
        UIAxes4                         matlab.ui.control.UIAxes
        InputSignalPanel                matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        DecoderPanel                    matlab.ui.container.Panel
        CalculateNumberSequenceButton   matlab.ui.control.Button
        MessageBoxTextArea              matlab.ui.control.TextArea
        MessageBoxTextAreaLabel         matlab.ui.control.Label
        DecodingOptionDropDown          matlab.ui.control.DropDown
        DecodingOptionDropDownLabel     matlab.ui.control.Label
        InputParametersPanel            matlab.ui.container.Panel
        RestingDurationsEditField_2     matlab.ui.control.NumericEditField
        RestingDurationsEditField_2Label  matlab.ui.control.Label
        SignalingDurationsEditField_2   matlab.ui.control.NumericEditField
        SignalingDurationsEditField_2Label  matlab.ui.control.Label
        PlaySoundSwitch                 matlab.ui.control.Switch
        PlaySoundSwitchLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        pressed_keys = "";
        signal
        sampling_frequency = 44100;
        time_vector;
        file_name = 'output_audio.wav';
        duration = 1;
        spectogram_window;
        signal_2;
        method;
        time;
        algorithm;
    end
    
    methods (Access = private)

        function [ num ] = find_num(~, r,c )
            %FIND_NUM Summary of this function goes here
            %   Detailed explanation goes here
            num_array = [1,2,3;4,5,6;7,8,9;0,0,0];
            num = num_array(r,c);
        end
        
        function [S,F,T] = mySpectrogram(~,x,windowSize,nOverlap,nfft,fs) 
            % Compute the spectrogram of the input signal 'x'

            % Calculate the number of samples per hop
            hopSize = windowSize - nOverlap;
        
            % Determine the number of frames
            numFrames = fix((length(x) - nOverlap) / hopSize);
        
            % Initialize the spectrogram matrix
            S = zeros(nfft/2 + 1, numFrames);
        
            % Compute the spectrogram
            for i = 1:numFrames
                % Extract the current frame
                startIndex = (i - 1) * hopSize + 1;
                endIndex = startIndex + windowSize - 1;
                frame = x(startIndex:endIndex);
        
                % Apply the window function to the frame
                window = hann(windowSize);
                windowedFrame = frame .* window;
        
                % Compute the magnitude spectrum of the frame
                spectrum = abs(fft(windowedFrame, nfft));
        
                % Store the magnitude spectrum in the spectrogram matrix
                S(:, i) = spectrum(1:nfft/2 + 1);
            end
        
            % Compute the frequency and time vectors
            F = (0:nfft/2) * fs / nfft;
            T = (0:numFrames-1) * hopSize / fs;
                    
        end

        function number = decodeDTMF(app, y, Fs)
            dtmf_chars = ['1', '2', '3', '4', '5', '6', '7', '8', '9', '0'];
            freq_pairs = [
                697, 1209; 697, 1336; 697, 1477;
                770, 1209; 770, 1336; 770, 1477;
                852, 1209; 852, 1336; 852, 1477;
                941, 1336;];
        
            number = '';
        
            for i = 1:length(freq_pairs)
                freq1 = freq_pairs(i, 1);
                freq2 = freq_pairs(i, 2);
        
                % Compute Goertzel coefficients for each frequency
                k1 = round(0.5 + 2 * length(y) * freq1 / Fs);
                k2 = round(0.5 + 2 * length(y) * freq2 / Fs);
        
                % Apply Goertzel algorithm
                s1 = 0;
                s2 = 0;
                for n = 1:length(y)
                    s0 = y(n) + 2 * cos(2 * pi * freq1 / Fs) * s1 - s2;
                    s2 = s1;
                    s1 = s0;
                end
        
                % Calculate power at each frequency
                power1 = s2^2 + s1^2 - cos(2 * pi * freq1 / Fs) * s1 * s2;
                
                s1 = 0;
                s2 = 0;
                for n = 1:length(y)
                    s0 = y(n) + 2 * cos(2 * pi * freq2 / Fs) * s1 - s2;
                    s2 = s1;
                    s1 = s0;
                end
                
                power2 = s2^2 + s1^2 - cos(2 * pi * freq2 / Fs) * s1 * s2;
        
%                 disp(i);
%                 disp(power1);
%                 disp(power2);
        
                % Adjust thresholds based on signal characteristics
                threshold1 = 1e+5;
                threshold2 = 1e+5;
        
        
                % Determine if DTMF tone is present using thresholds
                if power1 > threshold1 && power2 > threshold2
                    % Find corresponding DTMF character
                    number = dtmf_chars(i);
                    break;
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.pressed_keys = strcat(app.pressed_keys, "1");
            app.PressedNumbersTextArea.Value = app.pressed_keys;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.pressed_keys = strcat(app.pressed_keys, "2");
            app.PressedNumbersTextArea.Value = app.pressed_keys;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.pressed_keys = strcat(app.pressed_keys, "3");
            app.PressedNumbersTextArea.Value = app.pressed_keys;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.pressed_keys = strcat(app.pressed_keys, "4");
            app.PressedNumbersTextArea.Value = app.pressed_keys;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.pressed_keys = strcat(app.pressed_keys, "5");
            app.PressedNumbersTextArea.Value = app.pressed_keys;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.pressed_keys = strcat(app.pressed_keys, "6");
            app.PressedNumbersTextArea.Value = app.pressed_keys;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.pressed_keys = strcat(app.pressed_keys, "7");
            app.PressedNumbersTextArea.Value = app.pressed_keys;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.pressed_keys = strcat(app.pressed_keys, "8");
            app.PressedNumbersTextArea.Value = app.pressed_keys;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.pressed_keys = strcat(app.pressed_keys, "9");
            app.PressedNumbersTextArea.Value = app.pressed_keys;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.pressed_keys = strcat(app.pressed_keys, "0");
            app.PressedNumbersTextArea.Value = app.pressed_keys;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.pressed_keys = "";
            app.PressedNumbersTextArea.Value = app.pressed_keys;
        end

        % Button pushed function: GenerateSignalButton
        function GenerateSignalButtonPushed(app, event)
            % DTMF frequencies table
            frequencies = {[697, 1209], [697, 1336], [697, 1477],[770, 1209], [770, 1336], [770, 1477], [852, 1209], [852, 1336], [852, 1477], [941, 1336]};
            
            % Get the parameters from the app
            amplitude = app.AmplitudeEditField.Value;
            signaling_duration = app.SignalingDurationsEditField.Value;
            resting_duration = app.RestingDurationsEditField.Value;
            
            % Convert pressed keys string to an array of numeric values
            pressed_keys_numeric = str2double(regexp(app.pressed_keys, '\d', 'match'));
            
            total_duration = length(pressed_keys_numeric) * (signaling_duration + resting_duration);
            app.duration = total_duration;
            % Preallocate space for time vector and signal
            app.time_vector = 0:1/app.sampling_frequency:total_duration;
            app.signal = zeros(size(app.time_vector));
            
            for i = 1:length(pressed_keys_numeric)
                % Calculate start and end times for the key press
                start_time = (i - 1) * (signaling_duration + resting_duration);
                key_end_time = start_time + signaling_duration;
                
                % Time vector for one key press
                t_key = start_time:1/app.sampling_frequency:key_end_time;
                % Time vector for one key rest
                %t_rest = key_end_time:1/app.sampling_frequency:end_time;
                
                key = pressed_keys_numeric(i);
                if (key == 0)
                    key = 10;
                end    
                
                tones = frequencies{key};

                % Generate the signal for the current key press using cosine waves
                key_signal = amplitude* 0.5 * (cos(2 * pi * tones(1) * t_key) + cos(2 * pi * tones(2) * t_key));
    
                % Assign the generated signal to the appropriate segment in app.signal
                key_indices = round(start_time * app.sampling_frequency) + 1 : round(key_end_time * app.sampling_frequency) + 1;
                app.signal(key_indices) = key_signal;
            end

            % Plotting the signal on UIAxes
            plot(app.UIAxes, app.time_vector, app.signal);
            xlabel(app.UIAxes, 'Time');
            ylabel(app.UIAxes, 'Signal');
            title(app.UIAxes, 'DTMF Signal');
            app.TextArea.Value = "Signal is plotted";
            pause(2);
            app.TextArea.Value = "";
        end

        % Value changed function: SignalingDurationsEditField
        function SignalingDurationsEditFieldValueChanged(app, event)
            app.SignalingDurationsEditField.Value;
        end

        % Value changed function: RestingDurationsEditField
        function RestingDurationsEditFieldValueChanged(app, event)
            app.RestingDurationsEditField.Value;
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.FileNameEditField.Value;
            app.file_name = strcat(app.FileNameEditField.Value,".wav");
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            sound(app.signal, app.sampling_frequency);
            app.TextArea.Value = "Playing The Sound...";
            pause(app.duration);
            app.TextArea.Value = "";
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite(app.file_name, app.signal, app.sampling_frequency);
            app.TextArea.Value = strcat("Signal is recorded as ",app.file_name);
            pause(2);
            app.TextArea.Value = "";
        end

        % Value changed function: WindowLengthEditField
        function WindowLengthEditFieldValueChanged(app, event)
            app.WindowLengthEditField.Value;
        end

        % Value changed function: ShiftAmountEditField
        function ShiftAmountEditFieldValueChanged(app, event)
            app.ShiftAmountEditField.Value;
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            %%%%%%%Plotting The Spectrogram
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Compute the spectrogram
            %windowSize = round(app.sampling_frequency * resting_duration); % Window size for spectrogram
            %nOverlap = round(app.sampling_frequency * resting_duration / 2); % Overlap for spectrogram

            windowSize = app.WindowLengthEditField.Value;
            nOverlap = app.ShiftAmountEditField.Value;
            nfft = 2^(nextpow2(windowSize)); % Number of FFT points for spectrogram
            
            % Divide the signal into overlapping segments
            numSegments = floor((length(app.signal) - windowSize) / (windowSize - nOverlap)) + 1;
            segments = zeros(windowSize, numSegments);
            for i = 1:numSegments
                startIdx = (i-1) * (windowSize - nOverlap) + 1;
                endIdx = startIdx + windowSize - 1;
                segments(:, i) = app.signal(startIdx:endIdx);
            end
            
            % Apply FFT on each segment
            spectrogramData = zeros(nfft, numSegments);
            for i = 1:numSegments
                segment = segments(:, i);
                segmentFreq = fft(segment, nfft);
                spectrogramData(:, i) = abs(segmentFreq);
            end
            
            % Convert spectrogram data to dB scale
            spectrogramData = 20*log10(spectrogramData);
            
            % Generate the time and frequency axes
            tAxis = (windowSize-nOverlap) * (0:numSegments-1) / app.sampling_frequency;
            fAxis = app.sampling_frequency * (0:nfft/2-1) / nfft;

            
            imagesc(app.UIAxes2, tAxis, fAxis, spectrogramData(1:nfft/2, :));
            set(gca, 'YDir', 'normal');
            cbar = colorbar(app.UIAxes2);
            ylabel(cbar, 'Power/Frequency(dB/Hz)');
            xlabel(app.UIAxes2,'Time (s)');
            title(app.UIAxes2,'Spectrogram');
            
        end

        % Value changed function: SignalingDurationsEditField_2
        function SignalingDurationsEditField_2ValueChanged(app, event)
            app.SignalingDurationsEditField_2.Value;
            
        end

        % Value changed function: RecordingTimeEditField
        function RecordingTimeEditFieldValueChanged(app, event)
            app.RecordingTimeEditField.Value;
            
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            app.MessageBoxTextArea.Value = "Recording Started";
            recObj = audiorecorder(app.sampling_frequency,16,1);
            recordblocking(recObj, app.RecordingTimeEditField.Value);
            app.signal_2 = getaudiodata(recObj);
            app.MessageBoxTextArea.Value = "Recording Finished";
            app.time = linspace(0, length(app.signal_2)/app.sampling_frequency, length(app.signal_2));

            if isequal(app.PlaySoundSwitch.Value, "On")
                app.MessageBoxTextArea.Value = "Playing the Sound";
                sound(app.signal_2, app.sampling_frequency);
                pause(app.RecordingTimeEditField.Value);
                app.MessageBoxTextArea.Value = "Played the Sound";
            end

            % Plotting the signal on UIAxes
            plot(app.UIAxes3, app.time, app.signal_2);
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Signal');
            title(app.UIAxes3, 'DTMF Signal');
            app.TextArea.Value = "Signal is plotted";
            pause(2);
            app.TextArea.Value = "";

            app.algorithm = "microphone";
            
        end

        % Value changed function: PlaySoundSwitch
        function PlaySoundSwitchValueChanged(app, event)
            app.PlaySoundSwitch.Value;
            if isequal(app.PlaySoundSwitch.Value, "On")
                app.MessageBoxTextArea.Value = "Playing the Sound";
                sound(app.signal_2, app.sampling_frequency);
                pause(app.RecordingTimeEditField.Value);
                app.MessageBoxTextArea.Value = "Played the Sound";
            end
        end

        % Drop down opening function: DecodingOptionDropDown
        function DecodingOptionDropDownOpening(app, event)
            app.DecodingOptionDropDown.Value
        end

        % Button pushed function: CalculateNumberSequenceButton
        function CalculateNumberSequenceButtonPushed(app, event)
            switch app.DecodingOptionDropDown.Value
                case "Spectogram Based"
                    bw = 50;
                    sourcetones = bsxfun(@plus,[697 770 852 941 1209 1336 1477 1633]',[-bw/2 bw/2]);
                    symbols = {'1','2','3','0';'4','5','6','0';'7','8','9','0';'0','0','0','0'};
                    tonemask = movmax(abs(app.signal_2),app.sampling_frequency*0.02)>(0.3*max(abs(app.signal_2)));
                    tonemask([1 end]) = 0;
                    
                    % find approximate start and end of each tone
                    tmd = diff(tonemask);
                    starts = find(tmd==1);
                    stops = find(tmd==-1);
                    numkeys = numel(starts);
                    
                    keys = [];
                    for k=1:numkeys
                        % fetch this chunk of the signal vector
                        thissignal = app.signal_2(starts(k):stops(k));
                        
                        % run fft on this chunk
                        fsignal=abs(fft(thissignal));   
                        
                        % find sum of spectrum with each frequency bin
                        d = numel(fsignal)/app.sampling_frequency;
                        tonepower = zeros(8,1);
                        for f=1:size(sourcetones,1)
	                        tonepower(f) = sum(fsignal(round(d*(sourcetones(f,1):sourcetones(f,2)))));
                        end
                        % find key matrix row and column indices from most powerful frequencies
                        [~,row] = max(tonepower(1:4));
                        [~,col] = max(tonepower(5:8));
                        % look up the symbol and add it to the list
                        thissymbol = symbols{row,col};
                        keys = [keys thissymbol];
                    end
                    
                    app.MessageBoxTextArea.Value = keys;
                    
                case "Goertzel"
                    if isequal(app.algorithm, "file")
                        %%%%%%%%%%works when sound signal is read from file%%%%%%%%%
                        y = app.signal_2;
                        Fs= app.sampling_frequency;
                        N = 100;
                        freqs = [697,770,852,941,1209,1336,1477];
                        inst_power = filter(ones(1,N)/N,1,y.*y);
                        threshold = 0.5*max(inst_power);
                        p_gt_t = zeros(1,length(y));
                        for i = (1:length(y))
                            if(inst_power(i)>threshold)
                                p_gt_t(i) = 1;
                            else
                                p_gt_t(i) = 0;
                            end
                        end
                        clear i;
                        beginning = find(p_gt_t(2:length(y)) - p_gt_t(1:length(y)-1)== 1);
                        ending = find(p_gt_t(2:length(y)) - p_gt_t(1:length(y)-1)== -1);
                        
                        len = ending - beginning + 1;
                        k = round(freqs' * len/Fs);
                        
                        Wn = exp(sqrt(-1)*2*pi*k./len);
                        nums = [];
                        for h = 1:length(beginning)
                            a = horzcat(ones(length(freqs),1),-Wn(:,h));
                            x1 = vertcat(y(beginning(h):ending(h)),[0]);
                            z1 = filter(1,a(1,:),x1);
                            z2 = filter(1,a(2,:),x1);
                            z3 = filter(1,a(3,:),x1);
                            z4 = filter(1,a(4,:),x1);
                            z5 = filter(1,a(5,:),x1);
                            z6 = filter(1,a(6,:),x1);
                            z7 = filter(1,a(7,:),x1);
                            z = [z1(len(h)+1),z2(len(h)+1),z3(len(h)+1),z4(len(h)+1),z5(len(h)+1),z6(len(h)+1),z7(len(h)+1)];
                            r = find(abs(z(1:4)) == max(abs(z(1:4))));
                            c = find(abs(z(5:7)) == max(abs(z(5:7))));
                            num_array = [1,2,3;4,5,6;7,8,9;0,0,0];
                            num = num_array(r,c);
                            nums = horzcat(nums, num);
                        end 
                        
                        app.MessageBoxTextArea.Value = num2str(nums); 

                    else
                        y = app.signal_2;
                        Fs = app.sampling_frequency;
                        tonemask = movmax(abs(y), Fs * 0.02) > (0.3 * max(abs(y)));
                        tonemask([1 end]) = 0;
                        
                        tmd = diff(tonemask);
                        starts = find(tmd == 1);
                        stops = find(tmd == -1);
                        
                        nums = [];
                        
                        figure(1);
                        
                        for i = 1:length(starts)
                            start_idx = starts(i);
                            end_idx = stops(i);
                            
                            % Extract the segment based on start and end times
                            tone_segment = y(start_idx:end_idx);
                            
                            % Perform DTMF decoding on this segment
                            number = app.decodeDTMF(tone_segment, Fs); % Pass the iteration number
                            disp(number);

                            % Plot the current tone segment
                            subplot(length(starts), 1, i);
                            plot(tone_segment);
                            title(['Corresponding Number: ', number ,' Tone Segment ', num2str(i)]);
                            
                            % Append the decoded number to the result array
                            nums = [nums, number];
                        end
                        
                        app.MessageBoxTextArea.Value = strcat("Goertzel:", num2str(nums)); 

                    end    
            end
        end

        % Value changed function: WindowLengthEditField_2
        function WindowLengthEditField_2ValueChanged(app, event)
            app.WindowLengthEditField_2.Value;
        end

        % Value changed function: ShiftAmountEditField_2
        function ShiftAmountEditField_2ValueChanged(app, event)
            app.ShiftAmountEditField_2.Value;
        end

        % Button pushed function: GraphButton_2
        function GraphButton_2Pushed(app, event)
            %%%%%%%Plotting The Spectrogram
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Compute the spectrogram
            %windowSize = round(app.sampling_frequency * resting_duration); % Window size for spectrogram
            %nOverlap = round(app.sampling_frequency * resting_duration / 2); % Overlap for spectrogram

            windowSize = app.WindowLengthEditField_2.Value;
            nOverlap = app.ShiftAmountEditField_2.Value;
            nfft = 2^(nextpow2(windowSize)); % Number of FFT points for spectrogram
            
            % Divide the signal into overlapping segments
            numSegments = floor((length(app.signal_2) - windowSize) / (windowSize - nOverlap)) + 1;
            segments = zeros(windowSize, numSegments);
            for i = 1:numSegments
                startIdx = (i-1) * (windowSize - nOverlap) + 1;
                endIdx = startIdx + windowSize - 1;
                segments(:, i) = app.signal_2(startIdx:endIdx);
            end
            
            % Apply FFT on each segment
            spectrogramData = zeros(nfft, numSegments);
            for i = 1:numSegments
                segment = segments(:, i);
                segmentFreq = fft(segment, nfft);
                spectrogramData(:, i) = abs(segmentFreq);
            end
            
            % Convert spectrogram data to dB scale
            spectrogramData = 20*log10(spectrogramData);
            
            % Generate the time and frequency axes
            tAxis = (windowSize-nOverlap) * (0:numSegments-1) / app.sampling_frequency;
            fAxis = app.sampling_frequency * (0:nfft/2-1) / nfft;

            
            imagesc(app.UIAxes4, tAxis, fAxis, spectrogramData(1:nfft/2, :));
            set(gca, 'YDir', 'normal');
            cbar = colorbar(app.UIAxes4);
            ylabel(cbar, 'Power/Frequency(dB/Hz)');
            xlabel(app.UIAxes4,'Time (s)');
            title(app.UIAxes4,'Spectrogram');            
        end

        % Button pushed function: ReadFromFileButton
        function ReadFromFileButtonPushed(app, event)
            app.algorithm = "file";
            file = uigetfile;
            [app.signal_2,app.sampling_frequency] = audioread(file);
            app.time = linspace(0, length(app.signal_2)/app.sampling_frequency, length(app.signal_2));
            app.MessageBoxTextArea.Value = 'File is read';
            pause(3);
            app.MessageBoxTextArea.Value = '';
            plot(app.UIAxes3, app.time, app.signal_2);
            xlabel(app.UIAxes3, 'Time');
            ylabel(app.UIAxes3, 'Signal');
            title(app.UIAxes3, 'DTMF Signal');
            app.MessageBoxTextArea.Value = "Signal is plotted";
            pause(3);
            app.TextArea.Value = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TransmitterTab = uitab(app.TabGroup);
            app.TransmitterTab.Title = 'Transmitter';
            app.PushButtonPanel = uipanel(app.TransmitterTab);
            app.PushButtonPanel.Title = 'Push-Button';
            app.Button = uibutton(app.PushButtonPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.PushButtonPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.PushButtonPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.PushButtonPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.PushButtonPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.PushButtonPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.PushButtonPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.PushButtonPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.PushButtonPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_11 = uibutton(app.PushButtonPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '0';
            app.GenerateSignalButton = uibutton(app.PushButtonPanel, 'push');
            app.GenerateSignalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalButtonPushed, true);
            app.GenerateSignalButton.Text = 'Generate Signal';
            app.PressedButtonsPanel = uipanel(app.TransmitterTab);
            app.PressedButtonsPanel.Title = 'Pressed Buttons';
            app.PressedNumbersTextArea = uitextarea(app.PressedButtonsPanel);
            app.ResetButton = uibutton(app.PressedButtonsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SignalParametersPanel = uipanel(app.TransmitterTab);
            app.SignalParametersPanel.Title = 'Signal Parameters';
            app.SignalingDurationsEditFieldLabel = uilabel(app.SignalParametersPanel);
            app.SignalingDurationsEditFieldLabel.Text = 'Signaling Duration(s)';
            app.SignalingDurationsEditField = uieditfield(app.SignalParametersPanel, 'numeric');
            app.SignalingDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @SignalingDurationsEditFieldValueChanged, true);
            app.RestingDurationsEditFieldLabel = uilabel(app.SignalParametersPanel);
            app.RestingDurationsEditFieldLabel.Text = 'Resting Duration(s)';
            app.RestingDurationsEditField = uieditfield(app.SignalParametersPanel, 'numeric');
            app.RestingDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @RestingDurationsEditFieldValueChanged, true);
            app.AmplitudeEditFieldLabel = uilabel(app.SignalParametersPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SignalParametersPanel, 'numeric');
            app.TimeDomainRepresentationPanel = uipanel(app.TransmitterTab);
            app.TimeDomainRepresentationPanel.Title = 'Time Domain Representation';
            app.UIAxes = uiaxes(app.TimeDomainRepresentationPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SpectrogramoftheSignalPanel = uipanel(app.TransmitterTab);
            app.SpectrogramoftheSignalPanel.Title = 'Spectrogram of the Signal';
            app.UIAxes2 = uiaxes(app.SpectrogramoftheSignalPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SaveorPlayPanel = uipanel(app.TransmitterTab);
            app.SaveorPlayPanel.Title = 'Save or Play';
            app.SaveButton = uibutton(app.SaveorPlayPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PlayButton = uibutton(app.SaveorPlayPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.FileNameEditFieldLabel = uilabel(app.SaveorPlayPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SaveorPlayPanel, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.SpectrogramParametersPanel = uipanel(app.TransmitterTab);
            app.SpectrogramParametersPanel.Title = 'Spectrogram Parameters';
            app.WindowLengthEditFieldLabel = uilabel(app.SpectrogramParametersPanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.SpectrogramParametersPanel, 'numeric');
            app.WindowLengthEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLengthEditFieldValueChanged, true);
            app.ShiftAmountEditFieldLabel = uilabel(app.SpectrogramParametersPanel);
            app.ShiftAmountEditFieldLabel.Text = 'Shift Amount';
            app.ShiftAmountEditField = uieditfield(app.SpectrogramParametersPanel, 'numeric');
            app.ShiftAmountEditField.ValueChangedFcn = createCallbackFcn(app, @ShiftAmountEditFieldValueChanged, true);
            app.GraphButton = uibutton(app.SpectrogramParametersPanel, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.MessageBoxPanel = uipanel(app.TransmitterTab);
            app.MessageBoxPanel.Title = 'Message Box';
            app.TextArea = uitextarea(app.MessageBoxPanel);
            app.ReceiverTab = uitab(app.TabGroup);
            app.ReceiverTab.Title = 'Receiver';
            app.PlaySoundSwitchLabel = uilabel(app.ReceiverTab);
            app.PlaySoundSwitchLabel.Text = 'PlaySound';
            app.PlaySoundSwitch = uiswitch(app.ReceiverTab, 'slider');
            app.PlaySoundSwitch.ValueChangedFcn = createCallbackFcn(app, @PlaySoundSwitchValueChanged, true);
            app.InputParametersPanel = uipanel(app.ReceiverTab);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.SignalingDurationsEditField_2Label = uilabel(app.InputParametersPanel);
            app.SignalingDurationsEditField_2Label.Text = 'Signaling Duration(s)';
            app.SignalingDurationsEditField_2 = uieditfield(app.InputParametersPanel, 'numeric');
            app.SignalingDurationsEditField_2.ValueChangedFcn = createCallbackFcn(app, @SignalingDurationsEditField_2ValueChanged, true);
            app.RestingDurationsEditField_2Label = uilabel(app.InputParametersPanel);
            app.RestingDurationsEditField_2Label.Text = 'Resting Duration(s)';
            app.RestingDurationsEditField_2 = uieditfield(app.InputParametersPanel, 'numeric');
            app.DecoderPanel = uipanel(app.ReceiverTab);
            app.DecoderPanel.Title = 'Decoder';
            app.DecodingOptionDropDownLabel = uilabel(app.DecoderPanel);
            app.DecodingOptionDropDownLabel.Text = 'Decoding Option';
            app.DecodingOptionDropDown = uidropdown(app.DecoderPanel);
            app.DecodingOptionDropDown.Items = {'Spectogram Based', 'Goertzel'};
            app.DecodingOptionDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DecodingOptionDropDownOpening, true);
            app.DecodingOptionDropDown.Value = 'Spectogram Based';
            app.MessageBoxTextAreaLabel = uilabel(app.DecoderPanel);
            app.MessageBoxTextAreaLabel.Text = 'Message Box';
            app.MessageBoxTextArea = uitextarea(app.DecoderPanel);
            app.CalculateNumberSequenceButton = uibutton(app.DecoderPanel, 'push');
            app.CalculateNumberSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateNumberSequenceButtonPushed, true);
            app.CalculateNumberSequenceButton.Text = 'Calculate Number Sequence';
            app.InputSignalPanel = uipanel(app.ReceiverTab);
            app.InputSignalPanel.Title = 'Input Signal';
            app.UIAxes3 = uiaxes(app.InputSignalPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SpectrogramPanel = uipanel(app.ReceiverTab);
            app.SpectrogramPanel.Title = 'Spectrogram';
            app.UIAxes4 = uiaxes(app.SpectrogramPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SpectrogramParametersPanel_2 = uipanel(app.ReceiverTab);
            app.SpectrogramParametersPanel_2.Title = 'Spectrogram Parameters';
            app.WindowLengthEditField_2Label = uilabel(app.SpectrogramParametersPanel_2);
            app.WindowLengthEditField_2Label.Text = 'Window Length';
            app.WindowLengthEditField_2 = uieditfield(app.SpectrogramParametersPanel_2, 'numeric');
            app.WindowLengthEditField_2.ValueChangedFcn = createCallbackFcn(app, @WindowLengthEditField_2ValueChanged, true);
            app.ShiftAmountEditField_2Label = uilabel(app.SpectrogramParametersPanel_2);
            app.ShiftAmountEditField_2Label.Text = 'Shift Amount';
            app.ShiftAmountEditField_2 = uieditfield(app.SpectrogramParametersPanel_2, 'numeric');
            app.ShiftAmountEditField_2.ValueChangedFcn = createCallbackFcn(app, @ShiftAmountEditField_2ValueChanged, true);
            app.GraphButton_2 = uibutton(app.SpectrogramParametersPanel_2, 'push');
            app.GraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @GraphButton_2Pushed, true);
            app.GraphButton_2.Text = 'Graph';
            app.InputPanel = uipanel(app.ReceiverTab);
            app.InputPanel.Title = 'Input';
            app.RecordingTimeEditFieldLabel = uilabel(app.InputPanel);
            app.RecordingTimeEditFieldLabel.Text = 'Recording Time';
            app.RecordingTimeEditField = uieditfield(app.InputPanel, 'numeric');
            app.RecordingTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RecordingTimeEditFieldValueChanged, true);
            app.StartRecordingButton = uibutton(app.InputPanel, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.ReadFromFileButton = uibutton(app.InputPanel, 'push');
            app.ReadFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFromFileButtonPushed, true);
            app.ReadFromFileButton.Text = 'Read From File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Part2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end